classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TrainRatioLabelTextPercentage  matlab.ui.control.Label
        TrainRatioLabelValue           matlab.ui.control.Label
        TrainRatioLabelText            matlab.ui.control.Label
        TestRatioEditField             matlab.ui.control.NumericEditField
        TestRatioEditFieldLabel        matlab.ui.control.Label
        ValRatioEditField              matlab.ui.control.NumericEditField
        ValRatioEditFieldLabel         matlab.ui.control.Label
        TrainingfunctionsListBox       matlab.ui.control.ListBox
        TrainingfunctionsListBoxLabel  matlab.ui.control.Label
        HiddenlayersizeEditField       matlab.ui.control.NumericEditField
        HiddenlayersizeEditFieldLabel  matlab.ui.control.Label
        TrainandplotButton             matlab.ui.control.Button
        PlotpretraineddataButton       matlab.ui.control.Button
        Fit                            matlab.ui.control.UIAxes
        Train                          matlab.ui.control.UIAxes
        Original                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = func(app)
            results = load("NatrenovanaData.mat");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            clear;                   
        end

        % Button pushed function: PlotpretraineddataButton
        function PlotpretraineddataButtonPushed(app, event)
            if ~exist("X")
                load langerRegrese.mat
            end
            cla(app.Original);
            cla(app.Train);
            cla(app.Fit);
            surf(app.Original,X,Y,Z,"FaceColor",'#0000FF');
            surf(app.Train,X,Y,Z_neuron,"FaceColor",'#FF0000');
            hold (app.Fit,"on")
            surf(app.Fit,X,Y,Z,'FaceAlpha','0.5',"FaceColor",'#0000FF');
            surf(app.Fit,X,Y,Z_neuron,'FaceAlpha','0.5',"FaceColor",'#FF0000')
            hold (app.Fit,"off")

        end

        % Button pushed function: TrainandplotButton
        function TrainandplotButtonPushed(app, event)
            load langerDataSet.mat
            
            % Solve an Input-Output Fitting problem with a Neural Network
            % Script generated by Neural Fitting app
            % Created 27-Apr-2022 10:10:51
            %
            % This script assumes these variables are defined:
            %
            %   XX - input data.
            %   Z - target data.
            
            x = XX;
            t = Z;
            
            % Choose a Training Function
            % For a list of all training functions type: help nntrain
            % 'trainlm' is usually fastest.
            % 'trainbr' takes longer but may be better for challenging problems.
            % 'trainscg' uses less memory. Suitable in low memory situations.
            trainFcn = 'trainlm';  % Levenberg-Marquardt backpropagation.
            
            % Create a Fitting Network
            hiddenLayerSize = app.HiddenlayersizeEditField.Value;
            net = fitnet(hiddenLayerSize,trainFcn);
            
            % Setup Division of Data for Training, Validation, Testing
            net.divideParam.trainRatio = str2double(app.TrainRatioLabelValue.Text)/100;
            net.divideParam.valRatio = app.ValRatioEditField.Value/100;
            net.divideParam.testRatio = app.TestRatioEditField.Value/100;
            
            % Train the Network
            [net,tr] = train(net,x,t);
            
            % Test the Network
            y = net(x);
            e = gsubtract(t,y);
            performance = perform(net,t,y)
           
            % View the Network
            %view(net)
            
            % Plots
            % Uncomment these lines to enable various plots.
            %figure, plotperform(tr)
            %figure, plottrainstate(tr)
            %figure, ploterrhist(e)
            %figure, plotregression(t,y)
            %figure, plotfit(net,x,t)
            
            Z_neuron = y;
            cla(app.Original);
            cla(app.Train);
            cla(app.Fit);
            surf(app.Original,X,Y,Z,"FaceColor",'#0000FF');
            surf(app.Train,X,Y,Z_neuron,"FaceColor",'#FF0000');
            
            hold (app.Fit,"on")
            surf(app.Fit,X,Y,Z,'FaceAlpha','0.5',"FaceColor",'#0000FF');
            surf(app.Fit,X,Y,Z_neuron,'FaceAlpha','0.5',"FaceColor",'#FF0000')
            hold (app.Fit,"off")

        end

        % Value changed function: ValRatioEditField
        function ValRatioEditFieldValueChanged(app, event)
            valueVal = app.ValRatioEditField.Value;
            valueTest = app.TestRatioEditField.Value;

            app.TrainRatioLabelValue.Text = string(100 - valueVal - valueTest);
        end

        % Value changed function: TestRatioEditField
        function TestRatioEditFieldValueChanged(app, event)
            valueVal = app.ValRatioEditField.Value;
            valueTest = app.TestRatioEditField.Value;

            app.TrainRatioLabelValue.Text = string(100 - valueVal - valueTest);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Original = uiaxes(app.UIFigure);
            title(app.Original, 'Původní data')
            xlabel(app.Original, 'X')
            ylabel(app.Original, 'Y')
            zlabel(app.Original, 'Z')
            app.Train = uiaxes(app.UIFigure);
            title(app.Train, 'Natrénovaná data')
            xlabel(app.Train, 'X')
            ylabel(app.Train, 'Y')
            zlabel(app.Train, 'Z')
            app.Fit = uiaxes(app.UIFigure);
            title(app.Fit, 'Porovanání dat')
            xlabel(app.Fit, 'X')
            ylabel(app.Fit, 'Y')
            zlabel(app.Fit, 'Z')
            app.PlotpretraineddataButton = uibutton(app.UIFigure, 'push');
            app.PlotpretraineddataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotpretraineddataButtonPushed, true);
            app.PlotpretraineddataButton.Text = 'Plot pretrained data';
            app.TrainandplotButton = uibutton(app.UIFigure, 'push');
            app.TrainandplotButton.ButtonPushedFcn = createCallbackFcn(app, @TrainandplotButtonPushed, true);
            app.TrainandplotButton.Text = 'Train and plot';
            app.HiddenlayersizeEditFieldLabel = uilabel(app.UIFigure);
            app.HiddenlayersizeEditFieldLabel.Text = 'Hidden layer size';
            app.HiddenlayersizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.HiddenlayersizeEditField.Limits = [1 Inf];
            app.HiddenlayersizeEditField.RoundFractionalValues = 'on';
            app.HiddenlayersizeEditField.Value = 10;
            app.TrainingfunctionsListBoxLabel = uilabel(app.UIFigure);
            app.TrainingfunctionsListBoxLabel.Text = 'Training functions';
            app.TrainingfunctionsListBox = uilistbox(app.UIFigure);
            app.TrainingfunctionsListBox.Items = {'trainlm', 'trainbr', 'trainscg'};
            app.TrainingfunctionsListBox.Value = 'trainlm';
            app.ValRatioEditFieldLabel = uilabel(app.UIFigure);
            app.ValRatioEditFieldLabel.Text = 'Val Ratio';
            app.ValRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValRatioEditField.Limits = [1 35];
            app.ValRatioEditField.RoundFractionalValues = 'on';
            app.ValRatioEditField.ValueChangedFcn = createCallbackFcn(app, @ValRatioEditFieldValueChanged, true);
            app.ValRatioEditField.Value = 15;
            app.TestRatioEditFieldLabel = uilabel(app.UIFigure);
            app.TestRatioEditFieldLabel.Text = 'Test Ratio';
            app.TestRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.TestRatioEditField.Limits = [1 35];
            app.TestRatioEditField.RoundFractionalValues = 'on';
            app.TestRatioEditField.ValueChangedFcn = createCallbackFcn(app, @TestRatioEditFieldValueChanged, true);
            app.TestRatioEditField.Value = 15;
            app.TrainRatioLabelText = uilabel(app.UIFigure);
            app.TrainRatioLabelText.Text = 'Train Ratio';
            app.TrainRatioLabelValue = uilabel(app.UIFigure);
            app.TrainRatioLabelValue.Text = '70';
            app.TrainRatioLabelTextPercentage = uilabel(app.UIFigure);
            app.TrainRatioLabelTextPercentage.Text = '%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end